classdef tanks_modelling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        information                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        infoTitle                       matlab.ui.control.Label
        creator                         matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        helpButton                      matlab.ui.control.Button
        oneTank                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        oneTankTitle                    matlab.ui.control.Label
        AlturadeltanqueLabel            matlab.ui.control.Label
        oneTankHeight                   matlab.ui.control.NumericEditField
        oneTankAxes                     matlab.ui.control.UIAxes
        TipodesalidadeflujoSwitch_2Label  matlab.ui.control.Label
        oneTankSwitch                   matlab.ui.control.Switch
        readeltanqueLabel               matlab.ui.control.Label
        oneTankArea                     matlab.ui.control.NumericEditField
        NiveldeltanqueLabel             matlab.ui.control.Label
        oneTankLevel                    matlab.ui.control.NumericEditField
        FlujodeentradaLabel_2           matlab.ui.control.Label
        oneTankFlowFluid                matlab.ui.control.NumericEditField
        TSimulacinLabel_3               matlab.ui.control.Label
        oneTankSimTime                  matlab.ui.control.NumericEditField
        oneTankSimButton                matlab.ui.control.Button
        twoTanks                        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        twoTanksTitle                   matlab.ui.control.Label
        twoTanksTank1                   matlab.ui.control.UIAxes
        twoTanksTank2                   matlab.ui.control.UIAxes
        TipodesalidadeflujoSwitchLabel  matlab.ui.control.Label
        twoTanksSwitch                  matlab.ui.control.Switch
        Alturadetanque1Label            matlab.ui.control.Label
        twoTanksHeight1                 matlab.ui.control.NumericEditField
        readeltanque1Label              matlab.ui.control.Label
        twoTanksArea1                   matlab.ui.control.NumericEditField
        Niveldeltanque1Label            matlab.ui.control.Label
        twoTanksLevel1                  matlab.ui.control.NumericEditField
        FlujodeentradaLabel             matlab.ui.control.Label
        twoTanksFluidIn                 matlab.ui.control.NumericEditField
        TSimulacinLabel_2               matlab.ui.control.Label
        twoTankSimTime                  matlab.ui.control.NumericEditField
        Alturadetanque2Label            matlab.ui.control.Label
        twoTanksHeight2                 matlab.ui.control.NumericEditField
        readeltanque2Label              matlab.ui.control.Label
        twoTanksArea2                   matlab.ui.control.NumericEditField
        Niveldeltanque2Label            matlab.ui.control.Label
        twoTanksLevel2                  matlab.ui.control.NumericEditField
        FlujodesalidaLabel              matlab.ui.control.Label
        twoTanksFluidOut                matlab.ui.control.NumericEditField
        twoTanksSimButton               matlab.ui.control.Button
        twoTanksPID                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        twoTanksPIDTItle                matlab.ui.control.Label
        readeltanque1Label_2            matlab.ui.control.Label
        twoTanksPIDArea1                matlab.ui.control.NumericEditField
        twoTanksPIDAxes                 matlab.ui.control.UIAxes
        readeltanque2Label_2            matlab.ui.control.Label
        twoTanksPIDArea2                matlab.ui.control.NumericEditField
        Niveldeltanque1Label_2          matlab.ui.control.Label
        twoTanksPIDLevel1               matlab.ui.control.NumericEditField
        Setpointtanque2Label            matlab.ui.control.Label
        twoTanksPIDSetpoint             matlab.ui.control.NumericEditField
        FlujodeentradaLabel_3           matlab.ui.control.Label
        twoTanksPIDFluidIn              matlab.ui.control.NumericEditField
        FlujodesalidaLabel_2            matlab.ui.control.Label
        twoTanksPIDFluidOut             matlab.ui.control.NumericEditField
        Alturadetanque2Label_2          matlab.ui.control.Label
        twoTanksPIDHeight               matlab.ui.control.NumericEditField
        KpLabel                         matlab.ui.control.Label
        twoTanksPIDKp                   matlab.ui.control.NumericEditField
        KiLabel                         matlab.ui.control.Label
        twoTanksPIDKi                   matlab.ui.control.NumericEditField
        KdEditFieldLabel_9              matlab.ui.control.Label
        twoTanksPIDKd                   matlab.ui.control.NumericEditField
        TSimulacinLabel                 matlab.ui.control.Label
        twoTanksPIDSimTime              matlab.ui.control.NumericEditField
        TAperturaLabel                  matlab.ui.control.Label
        twoTanksPIDDelay                matlab.ui.control.NumericEditField
        SIMULARButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: oneTankSimButton
        function oneTankSimButtonPushed(app, event)
            area = app.oneTankArea.Value;
            height = app.oneTankHeight.Value;
            level = app.oneTankLevel.Value;
            qin  = app.oneTankFlowFluid.Value;
            time = app.oneTankSimTime.Value;
            resistance = level/qin;            
            tao = area * resistance;
            type = app.oneTankSwitch.Value;
            assignin('base','area',area);
            assignin('base','level',level);
            assignin('base','qin',qin);
            assignin('base','resistance',resistance);
            assignin('base','time',time);
            assignin('base','tao',tao);
            if type == "Turbulento"
                simulation = sim('one_tank_turbulent.slx');
                time = simulation.time;
                data = simulation.data;
            else
                simulation = sim('one_tank_laminar.slx');
                time = simulation.time;
                data = simulation.data;
            end
            app.oneTankAxes.YLim = [0 height];
            plot(app.oneTankAxes, time, data);
        end

        % Button pushed function: twoTanksSimButton
        function twoTanksSimButtonPushed(app, event)
            area1 = app.twoTanksArea1.Value;
            area2 = app.twoTanksArea2.Value;
            height1 = app.twoTanksHeight1.Value;
            height2 = app.twoTanksHeight2.Value;
            level1 = app.twoTanksLevel1.Value;
            level2 = app.twoTanksLevel2.Value;
            qin = app.twoTanksFluidIn.Value;
            qout = app.twoTanksFluidOut.Value;
            time = app.twoTankSimTime.Value;
            resistance1 = (level1 - level2) / qin;
            resistance2 = level2 / qout;
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
            type = app.twoTanksSwitch.Value;
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','level2',level2);
            assignin('base','qin',qin);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            if type == "Turbulento"
                simulation = sim('two_coupled_tanks_turbulent');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            else
                simulation = sim('two_coupled_tanks_laminar');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            end
            app.twoTanksTank1.YLim = [0 height1];
            plot(app.twoTanksTank1, time, data1);
            app.twoTanksTank2.YLim = [0 height2];
            plot(app.twoTanksTank2, time, data2);
            
            
        end

        % Button pushed function: SIMULARButton
        function twoTanksPIDSimButtonPushed(app, event)
            area1 = app.twoTanksPIDArea1.Value;
            area2 = app.twoTanksPIDArea2.Value;
            height = app.twoTanksPIDHeight.Value;
            level1 = app.twoTanksPIDLevel1.Value;
            setpoint = app.twoTanksPIDSetpoint.Value;
            qin = app.twoTanksPIDFluidIn.Value;
            qout = app.twoTanksPIDFluidOut.Value;
            
            kp = app.twoTanksPIDKp.Value;
            ki = app.twoTanksPIDKi.Value;
            kd = app.twoTanksPIDKd.Value;
            time = app.twoTanksPIDSimTime.Value;
            delay = app.twoTanksPIDDelay.Value;
            
            resistance1 = level1 - setpoint / qin;
            resistance2 = setpoint / qout;
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
            
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','setpoint',setpoint);
            assignin('base','qin',qin);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            assignin('base','kp',kp);
            assignin('base','ki',ki);
            assignin('base','kd',kd);
            assignin('base','delay',delay);
            
            simulation = sim('two_coupled_tanks_PID');
            time = simulation.time;
           
            data2 = simulation.data2;
           
            app.twoTanksPIDAxes.YLim = [0 height];
            plot(app.twoTanksPIDAxes, time, data2);
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            open("Uso de interfaz gráfica para modelamiento de tanques acoplados.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.information = uitab(app.TabGroup);
            app.information.Title = 'Información';
            app.GridLayout = uigridlayout(app.information);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Tank Filling Modeling es una aplicación creada con la finalidad de que los estudiantes que encuentren cursando las materias Sistemas de control e Instrumentación industrial'; 'puedan interactuar en ella, y desarrollar practicas con respecto al llenado de diversos modelos de tanques acoplados en serie.'; ''};
            app.infoTitle = uilabel(app.GridLayout);
            app.infoTitle.Text = 'TANK FILLING MODELING';
            app.creator = uilabel(app.GridLayout);
            app.creator.Text = {'Creado por @jilverprivera | Universidad de Investigación y Desarrollo - UDI'; '2022-1'; ''};
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'En la parte superior izquierda podrá observar los distintos ejercicios propuestos para el desarrollo de prácticas académicas.';
            app.Label4 = uilabel(app.GridLayout);
            app.Label4.Text = 'Para ver el manual de uso de la interfaz gráfica de clic en el botón "ayuda" y un archivo PDF se abrirá mostrando así el instructivo.';
            app.helpButton = uibutton(app.GridLayout, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'Ayuda';
            app.oneTank = uitab(app.TabGroup);
            app.oneTank.Title = '1 Tanque';
            app.GridLayout2 = uigridlayout(app.oneTank);
            app.oneTankTitle = uilabel(app.GridLayout2);
            app.oneTankTitle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE UN TANQUE';
            app.AlturadeltanqueLabel = uilabel(app.GridLayout2);
            app.AlturadeltanqueLabel.Text = 'Altura del tanque';
            app.oneTankHeight = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankHeight.Limits = [0 Inf];
            app.oneTankAxes = uiaxes(app.GridLayout2);
            title(app.oneTankAxes, 'Llenado del tanque')
            xlabel(app.oneTankAxes, 'Tiempo (s)')
            ylabel(app.oneTankAxes, 'Altura (m)')
            app.TipodesalidadeflujoSwitch_2Label = uilabel(app.GridLayout2);
            app.TipodesalidadeflujoSwitch_2Label.Text = 'Tipo de salida de flujo';
            app.oneTankSwitch = uiswitch(app.GridLayout2, 'slider');
            app.oneTankSwitch.Items = {'Laminar', 'Turbulento'};
            app.oneTankSwitch.Value = 'Laminar';
            app.readeltanqueLabel = uilabel(app.GridLayout2);
            app.readeltanqueLabel.Text = 'Área del tanque';
            app.oneTankArea = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankArea.Limits = [0 Inf];
            app.NiveldeltanqueLabel = uilabel(app.GridLayout2);
            app.NiveldeltanqueLabel.Text = 'Nivel del tanque';
            app.oneTankLevel = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankLevel.Limits = [0 Inf];
            app.FlujodeentradaLabel_2 = uilabel(app.GridLayout2);
            app.FlujodeentradaLabel_2.Text = 'Flujo de entrada';
            app.oneTankFlowFluid = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankFlowFluid.Limits = [0 Inf];
            app.TSimulacinLabel_3 = uilabel(app.GridLayout2);
            app.TSimulacinLabel_3.Text = 'T. Simulación';
            app.oneTankSimTime = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankSimTime.Limits = [0 Inf];
            app.oneTankSimButton = uibutton(app.GridLayout2, 'push');
            app.oneTankSimButton.ButtonPushedFcn = createCallbackFcn(app, @oneTankSimButtonPushed, true);
            app.oneTankSimButton.Text = 'SIMULAR';
            app.twoTanks = uitab(app.TabGroup);
            app.twoTanks.Title = '2 Tanques';
            app.GridLayout3 = uigridlayout(app.twoTanks);
            app.twoTanksTitle = uilabel(app.GridLayout3);
            app.twoTanksTitle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE DOS TANQUE ACOPLADOS EN SERIE';
            app.twoTanksTank1 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank1, 'Llenado de tanque 1')
            xlabel(app.twoTanksTank1, 'Tiempo (s)')
            ylabel(app.twoTanksTank1, 'Altura (m)')
            app.twoTanksTank2 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank2, 'Llenado de tanque 2')
            xlabel(app.twoTanksTank2, 'Tiempo (s)')
            ylabel(app.twoTanksTank2, 'Altura (m)')
            app.TipodesalidadeflujoSwitchLabel = uilabel(app.GridLayout3);
            app.TipodesalidadeflujoSwitchLabel.Text = 'Tipo de salida de flujo';
            app.twoTanksSwitch = uiswitch(app.GridLayout3, 'slider');
            app.twoTanksSwitch.Items = {'Laminar', 'Turbulento'};
            app.twoTanksSwitch.Value = 'Laminar';
            app.Alturadetanque1Label = uilabel(app.GridLayout3);
            app.Alturadetanque1Label.Text = 'Altura de tanque 1';
            app.twoTanksHeight1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksHeight1.Limits = [0 Inf];
            app.readeltanque1Label = uilabel(app.GridLayout3);
            app.readeltanque1Label.Text = 'Área del tanque 1';
            app.twoTanksArea1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksArea1.Limits = [0 Inf];
            app.Niveldeltanque1Label = uilabel(app.GridLayout3);
            app.Niveldeltanque1Label.Text = 'Nivel del tanque 1';
            app.twoTanksLevel1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksLevel1.Limits = [0 Inf];
            app.FlujodeentradaLabel = uilabel(app.GridLayout3);
            app.FlujodeentradaLabel.Text = 'Flujo de entrada';
            app.twoTanksFluidIn = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksFluidIn.Limits = [0 Inf];
            app.TSimulacinLabel_2 = uilabel(app.GridLayout3);
            app.TSimulacinLabel_2.Text = 'T. Simulación';
            app.twoTankSimTime = uieditfield(app.GridLayout3, 'numeric');
            app.twoTankSimTime.Limits = [0 Inf];
            app.Alturadetanque2Label = uilabel(app.GridLayout3);
            app.Alturadetanque2Label.Text = 'Altura de tanque 2';
            app.twoTanksHeight2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksHeight2.Limits = [0 Inf];
            app.readeltanque2Label = uilabel(app.GridLayout3);
            app.readeltanque2Label.Text = 'Área del tanque 2';
            app.twoTanksArea2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksArea2.Limits = [0 Inf];
            app.Niveldeltanque2Label = uilabel(app.GridLayout3);
            app.Niveldeltanque2Label.Text = 'Nivel del tanque 2';
            app.twoTanksLevel2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksLevel2.Limits = [0 Inf];
            app.FlujodesalidaLabel = uilabel(app.GridLayout3);
            app.FlujodesalidaLabel.Text = 'Flujo de salida';
            app.twoTanksFluidOut = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksFluidOut.Limits = [0 Inf];
            app.twoTanksSimButton = uibutton(app.GridLayout3, 'push');
            app.twoTanksSimButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksSimButtonPushed, true);
            app.twoTanksSimButton.Text = 'SIMULAR';
            app.twoTanksPID = uitab(app.TabGroup);
            app.twoTanksPID.Title = '2 Tanques [Control PID]';
            app.GridLayout4 = uigridlayout(app.twoTanksPID);
            app.twoTanksPIDTItle = uilabel(app.GridLayout4);
            app.twoTanksPIDTItle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE DOS TANQUE ACOPLADOS CON SISTEMA DE CONTROL PID PARA SEGUNDO TANQUE';
            app.readeltanque1Label_2 = uilabel(app.GridLayout4);
            app.readeltanque1Label_2.Text = 'Área del tanque 1';
            app.twoTanksPIDArea1 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDArea1.Limits = [0 Inf];
            app.twoTanksPIDAxes = uiaxes(app.GridLayout4);
            title(app.twoTanksPIDAxes, 'Llenado de tanque 2')
            xlabel(app.twoTanksPIDAxes, 'Tiempo (s)')
            ylabel(app.twoTanksPIDAxes, 'Altura (m)')
            app.readeltanque2Label_2 = uilabel(app.GridLayout4);
            app.readeltanque2Label_2.Text = 'Área del tanque 2';
            app.twoTanksPIDArea2 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDArea2.Limits = [0 Inf];
            app.Niveldeltanque1Label_2 = uilabel(app.GridLayout4);
            app.Niveldeltanque1Label_2.Text = 'Nivel del tanque 1';
            app.twoTanksPIDLevel1 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDLevel1.Limits = [0 Inf];
            app.Setpointtanque2Label = uilabel(app.GridLayout4);
            app.Setpointtanque2Label.Text = 'Setpoint tanque 2';
            app.twoTanksPIDSetpoint = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDSetpoint.Limits = [0 Inf];
            app.FlujodeentradaLabel_3 = uilabel(app.GridLayout4);
            app.FlujodeentradaLabel_3.Text = 'Flujo de entrada';
            app.twoTanksPIDFluidIn = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDFluidIn.Limits = [0 Inf];
            app.FlujodesalidaLabel_2 = uilabel(app.GridLayout4);
            app.FlujodesalidaLabel_2.Text = 'Flujo de salida ';
            app.twoTanksPIDFluidOut = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDFluidOut.Limits = [0 Inf];
            app.Alturadetanque2Label_2 = uilabel(app.GridLayout4);
            app.Alturadetanque2Label_2.Text = 'Altura de tanque 2';
            app.twoTanksPIDHeight = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDHeight.Limits = [0 Inf];
            app.KpLabel = uilabel(app.GridLayout4);
            app.KpLabel.Text = 'Kp';
            app.twoTanksPIDKp = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKp.Limits = [0 Inf];
            app.KiLabel = uilabel(app.GridLayout4);
            app.KiLabel.Text = 'Ki';
            app.twoTanksPIDKi = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKi.Limits = [0 Inf];
            app.KdEditFieldLabel_9 = uilabel(app.GridLayout4);
            app.KdEditFieldLabel_9.Text = 'Kd';
            app.twoTanksPIDKd = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKd.Limits = [0 Inf];
            app.TSimulacinLabel = uilabel(app.GridLayout4);
            app.TSimulacinLabel.Text = 'T. Simulación';
            app.twoTanksPIDSimTime = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDSimTime.Limits = [0 Inf];
            app.TAperturaLabel = uilabel(app.GridLayout4);
            app.TAperturaLabel.Text = 'T. Apertura';
            app.twoTanksPIDDelay = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDDelay.Limits = [0 Inf];
            app.SIMULARButton = uibutton(app.GridLayout4, 'push');
            app.SIMULARButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksPIDSimButtonPushed, true);
            app.SIMULARButton.Text = 'SIMULAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tanks_modelling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end